classdef ECE45Synth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BandRejectCheckBox           matlab.ui.control.CheckBox
        UseFileasTremoloCheckBox     matlab.ui.control.CheckBox
        UseFileasEnvelopeCheckBox    matlab.ui.control.CheckBox
        UseFileasWaveformCheckBox    matlab.ui.control.CheckBox
        ReverseaudioCheckBox         matlab.ui.control.CheckBox
        AudioFileTextArea            matlab.ui.control.TextArea
        AudioFileTextAreaLabel       matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
        EnableTremoloCheckBox        matlab.ui.control.CheckBox
        TremtempoSlider              matlab.ui.control.Slider
        TremtempoLabel               matlab.ui.control.Label
        TremmixSlider                matlab.ui.control.Slider
        TremmixSliderLabel           matlab.ui.control.Label
        FunctionDescriptionTextArea  matlab.ui.control.TextArea
        FunctionDescriptionTextAreaLabel  matlab.ui.control.Label
        NoteLengthSlider             matlab.ui.control.Slider
        NoteLengthLabel              matlab.ui.control.Label
        OctaveKnob                   matlab.ui.control.DiscreteKnob
        OctaveKnobLabel              matlab.ui.control.Label
        LowPassSlider                matlab.ui.control.Slider
        LowPassSliderLabel           matlab.ui.control.Label
        HighpassSlider               matlab.ui.control.Slider
        HighpassSliderLabel          matlab.ui.control.Label
        InstrumentsDropDown          matlab.ui.control.DropDown
        InstrumentsDropDownLabel     matlab.ui.control.Label
        FButton_3                    matlab.ui.control.Button
        DButton_2_Sharp              matlab.ui.control.Button
        CButton_2_Sharp              matlab.ui.control.Button
        DButton_2                    matlab.ui.control.Button
        CButton_2                    matlab.ui.control.Button
        AButton_2_Sharp              matlab.ui.control.Button
        GButton_2_Sharp              matlab.ui.control.Button
        FButton_2_Sharp              matlab.ui.control.Button
        DButton_1_Shapr              matlab.ui.control.Button
        CButton_1_Sharp              matlab.ui.control.Button
        AButton_1_Sharp              matlab.ui.control.Button
        GButton_1_Sharp              matlab.ui.control.Button
        FButton_1_Sharp              matlab.ui.control.Button
        BButton_2                    matlab.ui.control.Button
        AButton_2                    matlab.ui.control.Button
        GButton_2                    matlab.ui.control.Button
        FButton_2                    matlab.ui.control.Button
        EButton_1                    matlab.ui.control.Button
        DButton_1                    matlab.ui.control.Button
        CButton_1                    matlab.ui.control.Button
        BButton_1                    matlab.ui.control.Button
        AButton_1                    matlab.ui.control.Button
        GButton_1                    matlab.ui.control.Button
        FButton_1                    matlab.ui.control.Button
        EButton_3                    matlab.ui.control.Button
        PlotButtonGroup              matlab.ui.container.ButtonGroup
        TremoloButton                matlab.ui.control.RadioButton
        FilterButton                 matlab.ui.control.RadioButton
        EnvelopeButton               matlab.ui.control.RadioButton
        WaveformButton               matlab.ui.control.RadioButton
        SquaremixSlider              matlab.ui.control.Slider
        SquaremixLabel               matlab.ui.control.Label
        SawmixSlider                 matlab.ui.control.Slider
        SawmixSliderLabel            matlab.ui.control.Label
        TrianglemixSlider            matlab.ui.control.Slider
        TrianglemixLabel             matlab.ui.control.Label
        SinemixSlider                matlab.ui.control.Slider
        SinemixLabel                 matlab.ui.control.Label
        ECE45FakeSynthLabel          matlab.ui.control.Label
        ReleaseSlider                matlab.ui.control.Slider
        ReleaseSliderLabel           matlab.ui.control.Label
        SustainSlider                matlab.ui.control.Slider
        SustainSliderLabel           matlab.ui.control.Label
        DecaySlider                  matlab.ui.control.Slider
        DecaySliderLabel             matlab.ui.control.Label
        AttackSlider                 matlab.ui.control.Slider
        AttackSliderLabel            matlab.ui.control.Label
        VolumeSlider                 matlab.ui.control.Slider
        VolumeSliderLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Global variable declaration
        
        % The code/comment below is written by Ruilin Hu
        
        octave = 3; % default volume is 0+3 = 3
        noteLength = 5000; % default note length is 50*200=10000
        volume = 100; % default volume is 100
        instrument = '0'; % 0 = Default(pure frequency) 
        
        waveformSource = 0; %0 = waveform generated by synth
                        %1 = waveform read from file              
        waveform = @(t) sin(t); % default waveform is pure sine
        fileWaveform = zeros(5000, 1); %default file waveform
        
        envelopeSource = 0; %0 = envelope generated by synth
                        %1 = envelope read from file
        envelope = zeros(5000, 1); %default envelope does not change waveform
        fileEnvelope = zeros(5000, 1); %default file envelope
        tremoloSource = 0; %0 = tremolo generated by synth
                        %1 = tremolo read from file
        tremolo = @(t) 1; %default tremolo has no change in sound.
        fileTremolo = @(t) 1; %default file tremolo
        
        %ideal filter section default all waves pass
        highestPassingFreqMult = 0;
        lowestPassingFreqMult = 0;
        bandRej = 0; %0 = bandPass, 1 = bandReject
        
        frequencyMultiplierTable= load('freqmult.mat', 'FreqMultis');
        % load frequency Multipliers from Table in freqmult.mat
        % freqmult.mat has to be in the same directory as this
        % MATLAB app file.
        % The code/comment above is written by Ruilin Hu
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FButton_1
        function FButton_1Pushed(app, event)
            % function FButton_1Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the first (left to right)
            % F key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{1, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: FButton_1_Sharp
        function FButton_1_SharpPushed(app, event)
            % function FButton_1SharpPushed(app, event) by Ruilin Hu
            % This button function corresponds to the first (left to right)
            % F# key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{2, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: GButton_1
        function GButton_1Pushed(app, event)
            % function GButton_1Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the first (left to right)
            % G key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{3, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: GButton_1_Sharp
        function GButton_1_SharpPushed(app, event)
            % function GButton_1SharpPushed(app, event) by Ruilin Hu
            % This button function corresponds to the first (left to right)
            % G# key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{4, app.octave};
           
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: AButton_1
        function AButton_1Pushed(app, event)
            % function AButton_1Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the first (left to right)
            % A key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{5, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: AButton_1_Sharp
        function AButton_1_SharpPushed(app, event)
            % function AButton_1SharpPushed(app, event) by Ruilin Hu
            % This button function corresponds to the first (left to right)
            % F# key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{6, app.octave};
           
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: BButton_1
        function BButton_1Pushed(app, event)
            % function BButton_1Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the first (left to right)
            % B key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{7, app.octave};
           
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: CButton_1
        function CButton_1Pushed(app, event)
            % function CButton_1Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the first (left to right)
            % C key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{8, app.octave};
           
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: CButton_1_Sharp
        function CButton_1_SharpPushed(app, event)
            % function CButton_1SharpPushed(app, event) by Ruilin Hu
            % This button function corresponds to the first (left to right)
            % C# key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{9, app.octave};
          
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: DButton_1
        function DButton_1Pushed(app, event)
            % function DButton_1Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the first (left to right)
            % D key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{10, app.octave};
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: DButton_1_Shapr
        function DButton_1_ShaprPushed(app, event)
            % function DButton_1SharpPushed(app, event) by Ruilin Hu
            % This button function corresponds to the first (left to right)
            % D# key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{11, app.octave};
           
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: EButton_1
        function EButton_1Pushed(app, event)
            % function EButton_1Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the first (left to right)
            % E key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{12, app.octave};
          
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: FButton_2
        function FButton_2Pushed(app, event)
            % function FButton_2Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the second (left to right)
            % F key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{13, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: FButton_2_Sharp
        function FButton_2_SharpPushed(app, event)
            % function FButton_2SharpPushed(app, event) by Ruilin Hu
            % This button function corresponds to the second (left to right)
            % F# key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{14, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: GButton_2
        function GButton_2Pushed(app, event)
            % function GButton_2Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the second (left to right)
            % G key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{15, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: GButton_2_Sharp
        function GButton_2_SharpPushed(app, event)
            % function GButton_2SharpPushed(app, event) by Ruilin Hu
            % This button function corresponds to the second (left to right)
            % G# key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{16, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: AButton_2
        function AButton_2Pushed(app, event)
            % function AButton_2Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the second (left to right)
            % A key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{17, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: AButton_2_Sharp
        function AButton_2_SharpPushed(app, event)
            % function AButton_2SharpPushed(app, event) by Ruilin Hu
            % This button function corresponds to the second (left to right)
            % A# key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{18, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: BButton_2
        function BButton_2Pushed(app, event)
            % function BButton_2Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the second (left to right)
            % B key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{19, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: CButton_2
        function CButton_2Pushed(app, event)
            % function CButton_2Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the second (left to right)
            % C key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{20, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: CButton_2_Sharp
        function CButton_2_SharpPushed(app, event)
            % function CButton_2SharpPushed(app, event) by Ruilin Hu
            % This button function corresponds to the second (left to right)
            % C# key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{21, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: DButton_2
        function DButton_2Pushed(app, event)
            % function DButton_2Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the second (left to right)
            % D key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{22, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: DButton_2_Sharp
        function DButton_2_SharpPushed(app, event)
            % function DButton_2SharpPushed(app, event) by Ruilin Hu
            % This button function corresponds to the second (left to right)
            % D# key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{23, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: EButton_3
        function EButton_3Pushed(app, event)
            % function EButton_2Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the second (left to right)
            % E key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{24, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Button pushed function: FButton_3
        function FButton_3Pushed(app, event)
            % function FButton_3Pushed(app, event) by Ruilin Hu
            % This button function corresponds to the third (left to right)
            % F key on the piano keyboard. Once pressed, the application
            % will play the corresponding sound that represents the set 
            % waveform at the designated pitch, which is determined by octave.
            % clear sound; % clears sound from previous key press
            frequencyMultiplierForNote = app.frequencyMultiplierTable.FreqMultis{25, app.octave};
            
            if app.waveformSource == 0
                noteWaveform = app.waveform;
            else
                noteWaveform = app.fileWaveform;
            end
            if app.envelopeSource == 0
                noteEnvelope = app.envelope;
            else
                noteEnvelope = app.fileEnvelope;
            end
            if app.tremoloSource == 0
                noteTremolo = app.tremolo;
            else
                noteTremolo = app.fileTremolo;
            end
            
            if app.waveformSource == 1
                makeFileSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
                return;
            end
            
            if app.instrument == '0'
                makeSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.volume, app.noteLength);
            end
            if app.instrument == '1'
                makePianoSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '2'
                makeSaxSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '3'
                makeFluteSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
            if app.instrument == '4'
                makeElectricGuitarSound(noteWaveform, noteEnvelope, noteTremolo, frequencyMultiplierForNote, app.lowestPassingFreqMult, app.highestPassingFreqMult, app.bandRej, app.volume, app.noteLength);
            end
        end

        % Value changed function: AttackSlider
        function AttackSliderValueChanged(app, event)
            % The function is written by Natalie Sigurdson
            attack_value = app.AttackSlider.Value/100;
            decay_value = app.DecaySlider.Value/100;
            sustain_value = app.SustainSlider.Value/100;
            release_value = app.ReleaseSlider.Value/100;
            app.envelope = envelopeGen(attack_value, ...
                decay_value, sustain_value, release_value, app.noteLength);

            % Below codes for the plotting function added by Zhizhen (Averi) Yu.
            if (app.EnvelopeButton.Value)
                plot(app.UIAxes, app.envelope);
            end
        end

        % Value changed function: DecaySlider
        function DecaySliderValueChanged(app, event)
            % The function is written by Natalie Sigurdson
            attack_value = app.AttackSlider.Value/100;
            decay_value = app.DecaySlider.Value/100;
            sustain_value = app.SustainSlider.Value/100;
            release_value = app.ReleaseSlider.Value/100;
            app.envelope = envelopeGen(attack_value, ...
                decay_value, sustain_value, release_value, app.noteLength);

            % Below codes for the plotting function added by Zhizhen (Averi) Yu.
            if (app.EnvelopeButton.Value)
                plot(app.UIAxes, app.envelope);
            end
            
        end

        % Value changed function: SustainSlider
        function SustainSliderValueChanged(app, event)
            % The function is written by Natalie Sigurdson
            attack_value = app.AttackSlider.Value/100;
            decay_value = app.DecaySlider.Value/100;
            sustain_value = app.SustainSlider.Value/100;
            release_value = app.ReleaseSlider.Value/100;
            app.envelope = envelopeGen(attack_value, ...
                decay_value, sustain_value, release_value, app.noteLength);

            % Below codes for the plotting function added by Zhizhen (Averi) Yu.
            if (app.EnvelopeButton.Value)
                plot(app.UIAxes, app.envelope);
            end
        end

        % Value changed function: ReleaseSlider
        function ReleaseSliderValueChanged(app, event)
            % The function is written by Natalie Sigurdson
            attack_value = app.AttackSlider.Value/100;
            decay_value = app.DecaySlider.Value/100;
            sustain_value = app.SustainSlider.Value/100;
            release_value = app.ReleaseSlider.Value/100;
            app.envelope = envelopeGen(attack_value, ...
                decay_value, sustain_value, release_value, app.noteLength);

            % Below codes for the plotting function added by Zhizhen (Averi) Yu.
            if (app.EnvelopeButton.Value)
                plot(app.UIAxes, app.envelope);
            end
        end

        % Value changed function: OctaveKnob
        function OctaveKnobValueChanged(app, event)
            % The following section is written by Kevin Chang
            value = app.OctaveKnob.Value;
            app.FunctionDescriptionTextArea.Value ="This knob helps to change the octave of the notes";
            if value == '-2'
                app.octave = 1 ;
            end
            if value == '-1'
                app.octave = 2 ;
            end
            if value == '0'
                app.octave = 3 ;
            end
            if value == '+1'
                app.octave = 4 ;
            end
            if value == '+2'
                app.octave = 5 ;
            end

        end

        % Value changed function: TremtempoSlider
        function TremtempoSliderValueChanged(app, event)
            %This function is written by Zhizhen (Averi) Yu.
            tempo_value = app.TremtempoSlider.Value;
            %Below is wirtten by Kevin Chang
            app.FunctionDescriptionTextArea.Value ="This slider adjusts the frequency of tremolo";
            %above is wirtten by Kevin Chang

            if (app.EnableTremoloCheckBox.Value)
                app.tremolo = tremoloGen(app.TremmixSlider.Value/100, tempo_value/100);

                if (app.TremoloButton.Value)
                    %plot the tremolo
                    tremoloArr = zeros(app.noteLength,1);
                    for i = 1:app.noteLength
                        tremoloArr(i) = app.tremolo(i); 
                    end
                    plot(app.UIAxes, tremoloArr);
                end
            else
                app.tremolo = @(t) 1;
            end
        end

        % Value changed function: TremmixSlider
        function TremmixSliderValueChanged(app, event)
            %This function is written by Zhizhen (Averi) Yu.
            depth_value = app.TremmixSlider.Value/100;

            %Below is wirtten by Kevin Chang
            app.FunctionDescriptionTextArea.Value ="This slider adjusts the volume of tremolo";
            %above is wirtten by Kevin Chang

            if (app.EnableTremoloCheckBox.Value)
                app.tremolo = tremoloGen(depth_value, app.TremtempoSlider.Value/100);

                if (app.TremoloButton.Value)
                    %plot the tremolo
                    tremoloArr = zeros(app.noteLength,1);
                    for i = 1:app.noteLength
                        tremoloArr(i) = app.tremolo(i); 
                    end
                    plot(app.UIAxes, tremoloArr);
                end
            else
                app.tremolo = @(t) 1;
            end
        end

        % Value changed function: InstrumentsDropDown
        function InstrumentsDropDownValueChanged(app, event)
            % The following code skeleton is created by Ruilin Hu
            % Different instruments are written by creators of make
            % InstrumentSound.m.
            % The property instrument will change according to the
            % user's selection in the drop down box. The user will
            % not be able to change some values if a specific 
            % instrument emulation is selected.
            value = app.InstrumentsDropDown.Value;
            app.instrument = value;
            if value == '0' % Default = Custom waveform & envelope
                % The following section is written by Ruilin Hu
                % Enable all sliders to change values
                app.BandRejectCheckBox.Enable = 0;
                app.HighpassSlider.Enable = 0;
                app.LowPassSlider.Enable = 0;
                app.waveform = @(t) sin(t);
                app.SinemixSlider.Enable = 1;
                app.SinemixSlider.Value = 100;
                app.SawmixSlider.Enable = 1;
                app.SawmixSlider.Value = 0;
                app.SquaremixSlider.Enable = 1;
                app.SquaremixSlider.Value = 0;
                app.TrianglemixSlider.Enable = 1;
                app.TrianglemixSlider.Value = 0;
                app.AudioFileTextArea.Enable = 1;
                app.LoadButton.Enable = 1;
                app.ReverseaudioCheckBox.Enable = 1;
                app.UseFileasEnvelopeCheckBox.Enable = 1;
                app.UseFileasTremoloCheckBox.Enable = 1;
                app.UseFileasWaveformCheckBox.Enable = 1;
                %The section above is written by Ruilin Hu
            elseif value == '1' % Emulate Electric Piano sounds
                % The following section is written by Ruilin Hu
                % Disable Envelope and wave sliders to change values
                % Wave sliders will show the preset values of
                % The electric piano.
                app.BandRejectCheckBox.Enable = 1;
                app.HighpassSlider.Enable = 1;
                app.LowPassSlider.Enable = 1;
                app.SinemixSlider.Value = 95.5;
                app.SinemixSlider.Enable = 0;
                app.SawmixSlider.Value = 2;
                app.SawmixSlider.Enable = 0;
                app.SquaremixSlider.Value = 2.5;
                app.SquaremixSlider.Enable = 0;
                app.TrianglemixSlider.Value = 0;
                app.TrianglemixSlider.Enable = 0;
                app.AudioFileTextArea.Value = "";
                app.AudioFileTextArea.Enable = 0;
                app.LoadButton.Enable = 0;
                app.ReverseaudioCheckBox.Enable = 0;
                app.UseFileasEnvelopeCheckBox.Enable = 0;
                app.UseFileasTremoloCheckBox.Enable = 0;
                app.UseFileasWaveformCheckBox.Enable = 0;
                app.waveformSource = 0;
                app.envelopeSource = 0;
                app.tremoloSource = 0;
                %The section above is written by Ruilin Hu
            elseif value == '2' % Emulate Saxophone sounds
                % The following section is written by Ruilin Hu
                % Disable Envelope and wave sliders to change values
                % Wave sliders will show the preset values of
                % the virtual saxophone.
                app.BandRejectCheckBox.Enable = 1;
                app.HighpassSlider.Enable = 1;
                app.LowPassSlider.Enable = 1;
                app.SinemixSlider.Value = 95.5;
                app.SinemixSlider.Enable = 0;
                app.SawmixSlider.Value = 0;
                app.SawmixSlider.Enable = 0;
                app.SquaremixSlider.Value = 2.5;
                app.SquaremixSlider.Enable = 0;
                app.TrianglemixSlider.Value = 2;
                app.TrianglemixSlider.Enable = 0;
                app.AudioFileTextArea.Value = "";
                app.AudioFileTextArea.Enable = 0;
                app.LoadButton.Enable = 0;
                app.ReverseaudioCheckBox.Enable = 0;
                app.UseFileasEnvelopeCheckBox.Enable = 0;
                app.UseFileasTremoloCheckBox.Enable = 0;
                app.UseFileasWaveformCheckBox.Enable = 0;
                app.waveformSource = 0;
                app.envelopeSource = 0;
                app.tremoloSource = 0;
                %The section above is written by Ruilin Hu
            elseif value == '3' % Emulate Flute sounds
                % The following section is written by Ruilin Hu
                % Disable Envelope and wave sliders to change values
                % Wave sliders will show the preset values of
                % the emulated flute.
                app.BandRejectCheckBox.Enable = 1;
                app.HighpassSlider.Enable = 1;
                app.LowPassSlider.Enable = 1;
                app.SinemixSlider.Value = 95.5;
                app.SinemixSlider.Enable = 0;
                app.SawmixSlider.Value = 2.5;
                app.SawmixSlider.Enable = 0;
                app.SquaremixSlider.Value = 0;
                app.SquaremixSlider.Enable = 0;
                app.TrianglemixSlider.Value = 2;
                app.TrianglemixSlider.Enable = 0;
                app.AudioFileTextArea.Value = "";
                app.AudioFileTextArea.Enable = 0;
                app.LoadButton.Enable = 0;
                app.ReverseaudioCheckBox.Enable = 0;
                app.UseFileasEnvelopeCheckBox.Enable = 0;
                app.UseFileasTremoloCheckBox.Enable = 0;
                app.UseFileasWaveformCheckBox.Enable = 0;
                app.waveformSource = 0;
                app.envelopeSource = 0;
                app.tremoloSource = 0;
                %The section above is written by Ruilin Hu
            elseif value == '4' % Emulate Violin sounds
                % The following section is written by Ruilin Hu
                % Disable Envelope and wave sliders to change values
                % Wave sliders will show the preset values of
                % a digital violin.
                app.BandRejectCheckBox.Enable = 1;
                app.HighpassSlider.Enable = 1;
                app.LowPassSlider.Enable = 1;
                app.SinemixSlider.Value = 3.9;
                app.SinemixSlider.Enable = 0;
                app.SawmixSlider.Value = 90;
                app.SawmixSlider.Enable = 0;
                app.SquaremixSlider.Value = 0.5;
                app.SquaremixSlider.Enable = 0;
                app.TrianglemixSlider.Value = 0;
                app.TrianglemixSlider.Enable = 0;
                app.AudioFileTextArea.Value = "";
                app.AudioFileTextArea.Enable = 0;
                app.LoadButton.Enable = 0;
                app.ReverseaudioCheckBox.Enable = 0;
                app.UseFileasEnvelopeCheckBox.Enable = 0;
                app.UseFileasTremoloCheckBox.Enable = 0;
                app.UseFileasWaveformCheckBox.Enable = 0;
                app.waveformSource = 0;
                app.envelopeSource = 0;
                app.tremoloSource = 0;
                %The section above is written by Ruilin Hu
            end
        end

        % Value changed function: NoteLengthSlider
        function NoteLengthSliderValueChanged(app, event)
            % The following section is written by Kevin Chang
            value = app.NoteLengthSlider.Value;
            app.noteLength = round(value) * 200 + 5000;
            % The following section is added by Ruilin Hu
            % The envelope needs to be changed using the new note
            % length value. The function is written by Natalie.
            
            attack_value = app.AttackSlider.Value/100;
            decay_value = app.DecaySlider.Value/100;
            sustain_value = app.SustainSlider.Value/100;
            release_value = app.ReleaseSlider.Value/100;
            app.envelope = envelopeGen(attack_value, ...
                decay_value, sustain_value, release_value, app.noteLength);

            if (app.EnvelopeButton.Value)
                plot(app.UIAxes, app.envelope);
            end


            % The following section is added by Ruilin Hu
            % The file needs to be loaded again when the note length
            % changes.
            app.AudioFileTextArea.Value = "";
            app.ReverseaudioCheckBox.Enable = 0;
            app.UseFileasEnvelopeCheckBox.Enable = 0;
            app.UseFileasTremoloCheckBox.Enable = 0;
            app.UseFileasWaveformCheckBox.Enable = 0;
            app.waveformSource = 0;
            app.envelopeSource = 0;
            app.tremoloSource = 0;
            
            % This function is written by Zhizhen (Averi) Yu.
            % This section is added to  NoteLengthSliderValueChanged(app,
            % event) by Ruilin Hu
            % The graph needs to replot according to the new note length.
            tempo_value = app.TremtempoSlider.Value;
            
            if(app.TremoloButton.Value)
                if (app.EnableTremoloCheckBox.Value)
                    app.tremolo = tremoloGen(app.TremmixSlider.Value/100, tempo_value/100);
    
                    if (app.TremoloButton.Value)
                        %plot the tremolo
                        tremoloArr = zeros(app.noteLength,1);
                        for i = 1:app.noteLength
                            tremoloArr(i) = app.tremolo(i); 
                        end
                        plot(app.UIAxes, tremoloArr);
                    end
                else
                    app.tremolo = @(t) 1;
                end
                
                depth_value = app.TremmixSlider.Value/100;
                
                if (app.EnableTremoloCheckBox.Value)
                    app.tremolo = tremoloGen(depth_value, app.TremtempoSlider.Value/100);
    
                    if (app.TremoloButton.Value)
                        %plot the tremolo
                        tremoloArr = zeros(app.noteLength,1);
                        for i = 1:app.noteLength
                            tremoloArr(i) = app.tremolo(i); 
                        end
                        plot(app.UIAxes, tremoloArr);
                    end
                else
                    app.tremolo = @(t) 1;
                end
            end
            
            
        end

        % Value changed function: FunctionDescriptionTextArea
        function FunctionDescriptionTextAreaValueChanged(app, event)
            % The following section is written by Kevin Chang
         
             app.FunctionDescriptionTextArea.Value= "This selection allows the user to pick a preset emulated instrument from the drop down bar. Some functions might be diabled for instruments other than Custom.";
        end

        % Value changed function: AudioFileTextArea
        function AudioFileTextAreaValueChanged(app, event)
            % The following code is written by David Salzmann
            % Edited by Ruilin Hu
            value = app.AudioFileTextArea.Value;
            if value ~= ""
                app.ReverseaudioCheckBox.Enable = 1;
                app.UseFileasEnvelopeCheckBox.Enable = 1;
                app.UseFileasTremoloCheckBox.Enable = 1;
                app.UseFileasWaveformCheckBox.Enable = 1;
            else
                app.ReverseaudioCheckBox.Enable = 0;
                app.UseFileasEnvelopeCheckBox.Enable = 0;
                app.UseFileasTremoloCheckBox.Enable = 0;
                app.UseFileasWaveformCheckBox.Enable = 0;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % The following code is written by David Salzmann
            % Edited by Ruilin Hu
            value = app.AudioFileTextArea.Value;
            if value ~= ""
                app.ReverseaudioCheckBox.Value = 0;
                app.UseFileasEnvelopeCheckBox.Value = 0;
                app.UseFileasTremoloCheckBox.Value = 0;
                app.UseFileasWaveformCheckBox.Value = 0;
                value = value{1};
                [app.fileWaveform, ~] = ReadAudioFile(value, app.noteLength);
                [app.fileEnvelope, ~] = ReadAudioFile(value, app.noteLength);
                [app.fileTremolo, ~] = ReadAudioFile(value, app.noteLength);
            end
            %written by Yiyang Chen
            app.FunctionDescriptionTextArea.Value= "The button can load the audio file you choose.";
        end

        % Value changed function: UseFileasWaveformCheckBox
        function UseFileasWaveformCheckBoxValueChanged(app, event)
            % The following code is written by Ruilin Hu
            % The value of UseFileasWaveformCheckBox changes
            % the source of waveform.
            value = app.UseFileasWaveformCheckBox.Value;
            if value
                app.waveformSource = 1;
            else
                app.waveformSource = 0;
            end
            %written by Yiyang Chen
            app.FunctionDescriptionTextArea.Value ="The button is to turn the audio file into a waveform type";

        end

        % Value changed function: UseFileasEnvelopeCheckBox
        function UseFileasEnvelopeCheckBoxValueChanged(app, event)
            % The following code is written by Ruilin Hu
            % The value of UseFileasEnvelopeCheckBox changes
            % the source of envelope.
            value = app.UseFileasEnvelopeCheckBox.Value;
            if value
                app.envelopeSource = 1;
            else
                app.envelopeSource = 0;
            end
            %written by Yiyang Chen
            app.FunctionDescriptionTextArea.Value ="The button is to turn the audio file into envelope type.";
        end

        % Value changed function: UseFileasTremoloCheckBox
        function UseFileasTremoloCheckBoxValueChanged(app, event)
            % The following code is written by Ruilin Hu
            % The value of UseFileasTremoloCheckBox changes
            % the source of tremolo.
            value = app.UseFileasTremoloCheckBox.Value;
            if value
                app.tremoloSource = 1;
            else
                app.tremoloSource = 0;
            end
            %written by Yiyang Chen
            app.FunctionDescriptionTextArea.Value ="The button is to turn the audio file into tremolo type.";
        end

        % Value changed function: ReverseaudioCheckBox
        function ReverseaudioCheckBoxValueChanged(app, event)
            % The following code is written by Ruilin Hu
            % Whenever the value of the reverse audio checkbox
            % changes, the matrix will be flipped (reversed).
            app.fileWaveform = flip(app.fileWaveform);
            app.fileEnvelope = flip(app.fileEnvelope);
            app.fileTremolo = flip(app.fileTremolo);
            %written by Yiyang Chen
            app.FunctionDescriptionTextArea.Value ="The button is to make the audio file play from the end.";
        end

        % Value changed function: EnableTremoloCheckBox
        function EnableTremoloCheckBoxValueChanged(app, event)
            %This function is written by Zhizhen (Averi) Yu.
            tempo_value = app.TremtempoSlider.Value;
            
            if (app.EnableTremoloCheckBox.Value)
                app.tremolo = tremoloGen(app.TremmixSlider.Value/100, tempo_value/100);
                app.TremoloButton.Enable = 1;
                app.TremtempoSlider.Enable = 1;
                app.TremmixSlider.Enable = 1;
                if (app.TremoloButton.Value)
                    %plot the tremolo
                    tremoloArr = zeros(app.noteLength,1);
                    for i = 1:app.noteLength
                        tremoloArr(i) = app.tremolo(i); 
                    end
                    plot(app.UIAxes, tremoloArr);
                end
            else
                app.tremolo = @(t) 1;
                app.TremoloButton.Enable = 0;
                app.TremtempoSlider.Enable = 0;
                app.TremmixSlider.Enable = 0;
            end
            
            depth_value = app.TremmixSlider.Value/100;
            
            if (app.EnableTremoloCheckBox.Value)
                app.tremolo = tremoloGen(depth_value, app.TremtempoSlider.Value/100);
                app.TremoloButton.Enable = 1;
                app.TremtempoSlider.Enable = 1;
                app.TremmixSlider.Enable = 1;
                if (app.TremoloButton.Value)
                    %plot the tremolo
                    tremoloArr = zeros(app.noteLength,1);
                    for i = 1:app.noteLength
                        tremoloArr(i) = app.tremolo(i); 
                    end
                    plot(app.UIAxes, tremoloArr);
                end
            else
                app.tremolo = @(t) 1;
                app.TremoloButton.Enable = 0;
                app.TremtempoSlider.Enable = 0;
                app.TremmixSlider.Enable = 0;
            end

             % Following line written by Kamran Mapar:
            app.FunctionDescriptionTextArea.Value = "Enabling this will rapidly adjust the volume of the sound in a rhythmic fashion";
        end

        % Selection changed function: PlotButtonGroup
        function PlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotButtonGroup.SelectedObject;
            % The following section is compiled by Zhizhen Yu
            if selectedButton == app.WaveformButton
                %plot the waveform here by Ruilin Hu
                resultMatrix = zeros(1000, 1);
                for i = 1:1000
                    resultMatrix(i, 1) = app.waveform(i/100);
                end
                plot(app.UIAxes, resultMatrix);
                %Below written by Yiyang Chen
                app.FunctionDescriptionTextArea.Value = "The button is to plot the waveform on the user interface.";  
                %Above written by Yiyang Chen
            elseif selectedButton == app.FilterButton
                 %Below written by Yiyang Chen
                app.FunctionDescriptionTextArea.Value = "The button is to plot the filter on the user interface";
                %Above written by Yiyang Chen
                % The following codes written by Zhizhen (Averi) Yu. 
                if (app.BandRejectCheckBox.Value)
                    plot(app.UIAxes, plotBandRejectFilter(app.noteLength, app.HighpassSlider.Value, app.LowPassSlider.Value));
                else
                    if ((app.HighpassSlider.Value ~= 0) && (app.LowPassSlider.Value ~= 0))
                        plot(app.UIAxes, plotBandPassFilter(app.noteLength, app.HighpassSlider.Value, app.LowPassSlider.Value));
                    elseif ((app.HighpassSlider.Value == 0) && (app.LowPassSlider.Value ~= 0))
                        plot(app.UIAxes, PlotLowPassFilter(app.noteLength, app.LowPassSlider.Value));
                    else 
                        plot(app.UIAxes, plotHighPassFilter(app.noteLength, app.HighpassSlider.Value));
                    end
                end
            % The following section is compiled by Ruilin Hu
            elseif selectedButton == app.EnvelopeButton
                %Below written by Yiyang Chen
                app.FunctionDescriptionTextArea.Value = "The button is to plot the envelope on the user interface";
                %Above written by Yiyang Chen
                % The function is written by Natalie Sigurdson
                attack_value = app.AttackSlider.Value/100;
                decay_value = app.DecaySlider.Value/100;
                sustain_value = app.SustainSlider.Value/100;
                release_value = app.ReleaseSlider.Value/100;
                app.envelope = envelopeGen(attack_value, ...
                    decay_value, sustain_value, release_value, app.noteLength);
    
                % The following lines are added by Zhizhen Yu to
                % plot the envelope.
                if (app.EnvelopeButton.Value)
                    plot(app.UIAxes, app.envelope);
                end
            elseif selectedButton == app.TremoloButton
                %Below written by Yiyang Chen
                app.FunctionDescriptionTextArea.Value = "The button is to plot the tremolo on the user interface";
                %Above written by Yiyang Chen
                %This function is written by Zhizhen (Averi) Yu.
                tempo_value = app.TremtempoSlider.Value;
                
                if (app.EnableTremoloCheckBox.Value)
                    app.tremolo = tremoloGen(app.TremmixSlider.Value/100, tempo_value/100);
    
                    if (app.TremoloButton.Value)
                        %plot the tremolo
                        tremoloArr = zeros(app.noteLength,1);
                        for i = 1:app.noteLength
                            tremoloArr(i) = app.tremolo(i); 
                        end
                        plot(app.UIAxes, tremoloArr);
                    end
                else
                    app.tremolo = @(t) 1;
                end
                
                depth_value = app.TremmixSlider.Value/100;
                
                if (app.EnableTremoloCheckBox.Value)
                    app.tremolo = tremoloGen(depth_value, app.TremtempoSlider.Value/100);
    
                    if (app.TremoloButton.Value)
                        %plot the tremolo
                        tremoloArr = zeros(app.noteLength,1);
                        for i = 1:app.noteLength
                            tremoloArr(i) = app.tremolo(i); 
                        end
                        plot(app.UIAxes, tremoloArr);
                    end
                else
                    app.tremolo = @(t) 1;
                end
            end
        end

        % Value changing function: VolumeSlider
        function VolumeSliderValueChanging(app, event)
            app.FunctionDescriptionTextArea.Value = "Adjust this slider to change the volume of the sound";
            % Written by Kamran Mapar
        end

        % Value changing function: AttackSlider
        function AttackSliderValueChanging(app, event)
            app.FunctionDescriptionTextArea.Value = "Adjust the length it takes for the sound to reach a peak value. The synthesizer will tune upwards to this maximum value";
            % Written by Kamran Mapar
        end

        % Value changing function: DecaySlider
        function DecaySliderValueChanging(app, event)
            app.FunctionDescriptionTextArea.Value = "Adjust the period of time the sound plays and tunes downward after reaching its peak value";
            % Written by Kamran Mapar
          
        end

        % Value changing function: SustainSlider
        function SustainSliderValueChanging(app, event)
            app.FunctionDescriptionTextArea.Value = "Adjust the steady-state amplitude resulting from the decay stage";
            % Written by Kamran Mapar
         
        end

        % Value changing function: ReleaseSlider
        function ReleaseSliderValueChanging(app, event)
            app.FunctionDescriptionTextArea.Value = "Adjust the time it takes for the sound to fade out to silence";
            % Written by Kamran Mapar
        end

        % Value changing function: NoteLengthSlider
        function NoteLengthSliderValueChanging(app, event)
            app.FunctionDescriptionTextArea.Value = "Adjust the duration the note plays for";
            % Written by Kamran Mapar
            
        end

        % Value changed function: BandRejectCheckBox
        function BandRejectCheckBoxValueChanged(app, event)
          % The following codes for the plotting funtion added by Zhizhen (Averi) Yu.  
          if (app.BandRejectCheckBox.Value)
              app.bandRej = 1;
          else
              app.bandRej = 0;
          end
          if (app.FilterButton.Value)
              if (app.BandRejectCheckBox.Value)
                    plot(app.UIAxes, plotBandRejectFilter(app.noteLength, app.HighpassSlider.Value, app.LowPassSlider.Value));
                else
                    if ((app.HighpassSlider.Value ~= 0) && (app.LowPassSlider.Value ~= 0))
                        plot(app.UIAxes, plotBandPassFilter(app.noteLength, app.HighpassSlider.Value, app.LowPassSlider.Value));
                    elseif ((app.HighpassSlider.Value == 0) && (app.LowPassSlider.Value ~= 0))
                        plot(app.UIAxes, PlotLowPassFilter(app.noteLength, app.LowPassSlider.Value));
                    else 
                        plot(app.UIAxes, plotHighPassFilter(app.noteLength, app.HighpassSlider.Value));
                    end
              end
          end

          % The following line written by Kamran Mapar:
          app.FunctionDescriptionTextArea.Value = "Enabling this will attenuate lower and higher frequencies, while filtering out middle frequencies in between";
         
        end

        % Value changed function: SinemixSlider
        function SinemixSliderValueChanged(app, event)
           %written by Yiyang Chen
           value = app.SinemixSlider.Value;
           sum = app.SinemixSlider.Value + app.TrianglemixSlider.Value + app.SquaremixSlider.Value + app.SawmixSlider.Value;
           Sinemix = SinWaveGen(app.SinemixSlider.Value / sum );
            Squaremix = SqrWaveGen(app.SquaremixSlider.Value / sum );
            Trianglemix = generate_triangle(app.TrianglemixSlider.Value / sum );
            Sawmix = generate_sawtooth(app.SawmixSlider.Value / sum );
            app.waveform = @(t) Sinemix(t) + Squaremix(t) + Trianglemix(t) + Sawmix(t);
            %plot the waveform here by Ruilin Hu
            if(app.WaveformButton.Value)
                resultMatrix = zeros(1000, 1);
                for i = 1:1000
                    resultMatrix(i, 1) = app.waveform(i/100);
                end
                plot(app.UIAxes, resultMatrix);
            end
            %Below is wirtten by Kevin Chang
            app.FunctionDescriptionTextArea.Value ="This slider adjusts the ratio of sine wave";

        end

        % Value changed function: SawmixSlider
        function SawmixSliderValueChanged(app, event)
            value = app.SawmixSlider.Value;
            %written by Yiyang Chen
            sum = app.SinemixSlider.Value + app.TrianglemixSlider.Value + app.SquaremixSlider.Value + app.SawmixSlider.Value;
            Sinemix = SinWaveGen(app.SinemixSlider.Value / sum );
            Squaremix = SqrWaveGen(app.SquaremixSlider.Value / sum );
            Trianglemix = generate_triangle(app.TrianglemixSlider.Value / sum );
            Sawmix = generate_sawtooth(app.SawmixSlider.Value / sum );
            app.waveform = @(t) Sinemix(t) + Squaremix(t) + Trianglemix(t) + Sawmix(t);
            %plot the waveform here by Ruilin Hu
            if(app.WaveformButton.Value)
                resultMatrix = zeros(1000, 1);
                for i = 1:1000
                    resultMatrix(i, 1) = app.waveform(i/100);
                end
                plot(app.UIAxes, resultMatrix);
            end
            %Below is wirtten by Kevin Chang
            app.FunctionDescriptionTextArea.Value ="This slider adjusts the ratio of saw wave";
        end

        % Value changed function: HighpassSlider
        function HighpassSliderValueChanged(app, event)
          highPass_cutOff_value = app.HighpassSlider.Value; %modifed by Zhizhen (Averi) Yu.
          app.highestPassingFreqMult = round(app.HighpassSlider.Value);
          % The following codes for the plotting funtion added by Zhizhen (Averi) Yu.  
          if (app.FilterButton.Value)
              if (app.BandRejectCheckBox.Value)
                    plot(app.UIAxes, plotBandRejectFilter(app.noteLength, highPass_cutOff_value, app.LowPassSlider.Value));
                else
                    if ((highPass_cutOff_value ~= 0) && (app.LowPassSlider.Value ~= 0))
                        plot(app.UIAxes, plotBandPassFilter(app.noteLength, highPass_cutOff_value, app.LowPassSlider.Value));
                    elseif ((highPass_cutOff_value == 0) && (app.LowPassSlider.Value ~= 0))
                        plot(app.UIAxes, PlotLowPassFilter(app.noteLength, app.LowPassSlider.Value));
                    else 
                        plot(app.UIAxes, plotHighPassFilter(app.noteLength, highPass_cutOff_value));
                    end
              end
          end
            %Below is wirtten by Kevin Chang
            app.FunctionDescriptionTextArea.Value ="This silder passes signals with a frequency higher than a certain cutoff frequency";
        end

        % Value changed function: TrianglemixSlider
        function TrianglemixSliderValueChanged(app, event)
            value = app.TrianglemixSlider.Value;
            %written by Yiyang Chen
            sum = app.SinemixSlider.Value + app.TrianglemixSlider.Value + app.SquaremixSlider.Value + app.SawmixSlider.Value;
            Sinemix = SinWaveGen(app.SinemixSlider.Value / sum );
            Squaremix = SqrWaveGen(app.SquaremixSlider.Value / sum );
            Trianglemix = generate_triangle(app.TrianglemixSlider.Value / sum );
            Sawmix = generate_sawtooth(app.SawmixSlider.Value / sum );
            app.waveform = @(t) Sinemix(t) + Squaremix(t) + Trianglemix(t) + Sawmix(t);
            %plot the waveform here by Ruilin Hu
            if(app.WaveformButton.Value)
                resultMatrix = zeros(1000, 1);
                for i = 1:1000
                    resultMatrix(i, 1) = app.waveform(i/100);
                end
                plot(app.UIAxes, resultMatrix);
            end
            %Below is wirtten by Kevin Chang
            app.FunctionDescriptionTextArea.Value ="This slider adjusts the ratio of triangle wave";
        end

        % Value changed function: SquaremixSlider
        function SquaremixSliderValueChanged(app, event)
            value = app.SquaremixSlider.Value;
            %written by Yiyang Chen
            sum = app.SinemixSlider.Value + app.TrianglemixSlider.Value + app.SquaremixSlider.Value + app.SawmixSlider.Value;
            Sinemix = SinWaveGen(app.SinemixSlider.Value / sum );
            Squaremix = SqrWaveGen(app.SquaremixSlider.Value / sum );
            Trianglemix = generate_triangle(app.TrianglemixSlider.Value / sum );
            Sawmix = generate_sawtooth(app.SawmixSlider.Value / sum );
            app.waveform = @(t) Sinemix(t) + Squaremix(t) + Trianglemix(t) + Sawmix(t);
            %plot the waveform here by Ruilin Hu
            if(app.WaveformButton.Value)
                resultMatrix = zeros(1000, 1);
                for i = 1:1000
                    resultMatrix(i, 1) = app.waveform(i/100);
                end
                plot(app.UIAxes, resultMatrix);
            end
            %Below is wirtten by Kevin Chang
            app.FunctionDescriptionTextArea.Value ="This slider adjusts the ratio of square wave";
        end

        % Value changed function: LowPassSlider
        function LowPassSliderValueChanged(app, event)
            lowPass_cutOff_value = app.LowPassSlider.Value; %modified by Zhizhen (Averi) Yu. 
            app.lowestPassingFreqMult = round(lowPass_cutOff_value);
            % Below codes for the plotting function added by Zhizhen (Averi) Yu.
            if(app.FilterButton.Value)
                if (app.BandRejectCheckBox.Value)
                    plot(app.UIAxes, plotBandRejectFilter(app.noteLength, app.HighpassSlider.Value, lowPass_cutOff_value));
                else
                    
                    if ((app.HighpassSlider.Value ~= 0) && (lowPass_cutOff_value ~= 0))
                        plot(app.UIAxes, plotBandPassFilter(app.noteLength, app.HighpassSlider.Value, lowPass_cutOff_value));
                    elseif ((app.HighpassSlider.Value == 0) && (lowPass_cutOff_value ~= 0))
                        plot(app.UIAxes, PlotLowPassFilter(app.noteLength, lowPass_cutOff_value));
                    else 
                        plot(app.UIAxes, plotHighPassFilter(app.noteLength, app.HighpassSlider.Value));
                    end
                end
            end
            %Below is wirtten by Kevin Chang
            app.FunctionDescriptionTextArea.Value ="This silder passes signals with a frequency lower than a certain cutoff frequency";
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            volume_value = app.VolumeSlider.Value;  
            app.volume = volume_value;
            % written by Yiyang Chen
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'f(t)')
            zlabel(app.UIAxes, 'Z')
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.ValueChangingFcn = createCallbackFcn(app, @VolumeSliderValueChanging, true);
            app.VolumeSlider.Value = 100;
            app.AttackSliderLabel = uilabel(app.UIFigure);
            app.AttackSliderLabel.Text = 'Attack';
            app.AttackSlider = uislider(app.UIFigure);
            app.AttackSlider.ValueChangedFcn = createCallbackFcn(app, @AttackSliderValueChanged, true);
            app.AttackSlider.ValueChangingFcn = createCallbackFcn(app, @AttackSliderValueChanging, true);
            app.DecaySliderLabel = uilabel(app.UIFigure);
            app.DecaySliderLabel.Text = 'Decay';
            app.DecaySlider = uislider(app.UIFigure);
            app.DecaySlider.ValueChangedFcn = createCallbackFcn(app, @DecaySliderValueChanged, true);
            app.DecaySlider.ValueChangingFcn = createCallbackFcn(app, @DecaySliderValueChanging, true);
            app.SustainSliderLabel = uilabel(app.UIFigure);
            app.SustainSliderLabel.Text = 'Sustain';
            app.SustainSlider = uislider(app.UIFigure);
            app.SustainSlider.ValueChangedFcn = createCallbackFcn(app, @SustainSliderValueChanged, true);
            app.SustainSlider.ValueChangingFcn = createCallbackFcn(app, @SustainSliderValueChanging, true);
            app.ReleaseSliderLabel = uilabel(app.UIFigure);
            app.ReleaseSliderLabel.Text = 'Release';
            app.ReleaseSlider = uislider(app.UIFigure);
            app.ReleaseSlider.ValueChangedFcn = createCallbackFcn(app, @ReleaseSliderValueChanged, true);
            app.ReleaseSlider.ValueChangingFcn = createCallbackFcn(app, @ReleaseSliderValueChanging, true);
            app.ECE45FakeSynthLabel = uilabel(app.UIFigure);
            app.ECE45FakeSynthLabel.Text = 'ECE 45 FakeSynth';
            app.SinemixLabel = uilabel(app.UIFigure);
            app.SinemixLabel.Text = 'Sinemix';
            app.SinemixSlider = uislider(app.UIFigure);
            app.SinemixSlider.ValueChangedFcn = createCallbackFcn(app, @SinemixSliderValueChanged, true);
            app.SinemixSlider.Value = 100;
            app.TrianglemixLabel = uilabel(app.UIFigure);
            app.TrianglemixLabel.Text = 'Trianglemix';
            app.TrianglemixSlider = uislider(app.UIFigure);
            app.TrianglemixSlider.ValueChangedFcn = createCallbackFcn(app, @TrianglemixSliderValueChanged, true);
            app.SawmixSliderLabel = uilabel(app.UIFigure);
            app.SawmixSliderLabel.Text = 'Sawmix';
            app.SawmixSlider = uislider(app.UIFigure);
            app.SawmixSlider.ValueChangedFcn = createCallbackFcn(app, @SawmixSliderValueChanged, true);
            app.SquaremixLabel = uilabel(app.UIFigure);
            app.SquaremixLabel.Text = 'Squaremix';
            app.SquaremixSlider = uislider(app.UIFigure);
            app.SquaremixSlider.ValueChangedFcn = createCallbackFcn(app, @SquaremixSliderValueChanged, true);
            app.PlotButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonGroupSelectionChanged, true);
            app.PlotButtonGroup.Title = 'Plot';
            app.WaveformButton = uiradiobutton(app.PlotButtonGroup);
            app.WaveformButton.Text = 'Waveform';
            app.WaveformButton.Value = true;
            app.EnvelopeButton = uiradiobutton(app.PlotButtonGroup);
            app.EnvelopeButton.Text = 'Envelope';
            app.FilterButton = uiradiobutton(app.PlotButtonGroup);
            app.FilterButton.Text = 'Filter';
            app.TremoloButton = uiradiobutton(app.PlotButtonGroup);
            app.TremoloButton.Enable = 'off';
            app.TremoloButton.Text = 'Tremolo';
            app.EButton_3 = uibutton(app.UIFigure, 'push');
            app.EButton_3.ButtonPushedFcn = createCallbackFcn(app, @EButton_3Pushed, true);
            app.EButton_3.Text = 'E';
            app.FButton_1 = uibutton(app.UIFigure, 'push');
            app.FButton_1.ButtonPushedFcn = createCallbackFcn(app, @FButton_1Pushed, true);
            app.FButton_1.Text = 'F';
            app.GButton_1 = uibutton(app.UIFigure, 'push');
            app.GButton_1.ButtonPushedFcn = createCallbackFcn(app, @GButton_1Pushed, true);
            app.GButton_1.Text = 'G';
            app.AButton_1 = uibutton(app.UIFigure, 'push');
            app.AButton_1.ButtonPushedFcn = createCallbackFcn(app, @AButton_1Pushed, true);
            app.AButton_1.Text = 'A';
            app.BButton_1 = uibutton(app.UIFigure, 'push');
            app.BButton_1.ButtonPushedFcn = createCallbackFcn(app, @BButton_1Pushed, true);
            app.BButton_1.Text = 'B';
            app.CButton_1 = uibutton(app.UIFigure, 'push');
            app.CButton_1.ButtonPushedFcn = createCallbackFcn(app, @CButton_1Pushed, true);
            app.CButton_1.Text = 'C';
            app.DButton_1 = uibutton(app.UIFigure, 'push');
            app.DButton_1.ButtonPushedFcn = createCallbackFcn(app, @DButton_1Pushed, true);
            app.DButton_1.Text = 'D';
            app.EButton_1 = uibutton(app.UIFigure, 'push');
            app.EButton_1.ButtonPushedFcn = createCallbackFcn(app, @EButton_1Pushed, true);
            app.EButton_1.Text = 'E';
            app.FButton_2 = uibutton(app.UIFigure, 'push');
            app.FButton_2.ButtonPushedFcn = createCallbackFcn(app, @FButton_2Pushed, true);
            app.FButton_2.Text = 'F';
            app.GButton_2 = uibutton(app.UIFigure, 'push');
            app.GButton_2.ButtonPushedFcn = createCallbackFcn(app, @GButton_2Pushed, true);
            app.GButton_2.Text = 'G';
            app.AButton_2 = uibutton(app.UIFigure, 'push');
            app.AButton_2.ButtonPushedFcn = createCallbackFcn(app, @AButton_2Pushed, true);
            app.AButton_2.Text = 'A';
            app.BButton_2 = uibutton(app.UIFigure, 'push');
            app.BButton_2.ButtonPushedFcn = createCallbackFcn(app, @BButton_2Pushed, true);
            app.BButton_2.Text = 'B';
            app.FButton_1_Sharp = uibutton(app.UIFigure, 'push');
            app.FButton_1_Sharp.ButtonPushedFcn = createCallbackFcn(app, @FButton_1_SharpPushed, true);
            app.FButton_1_Sharp.Text = 'F#';
            app.GButton_1_Sharp = uibutton(app.UIFigure, 'push');
            app.GButton_1_Sharp.ButtonPushedFcn = createCallbackFcn(app, @GButton_1_SharpPushed, true);
            app.GButton_1_Sharp.Text = 'G#';
            app.AButton_1_Sharp = uibutton(app.UIFigure, 'push');
            app.AButton_1_Sharp.ButtonPushedFcn = createCallbackFcn(app, @AButton_1_SharpPushed, true);
            app.AButton_1_Sharp.Text = 'A#';
            app.CButton_1_Sharp = uibutton(app.UIFigure, 'push');
            app.CButton_1_Sharp.ButtonPushedFcn = createCallbackFcn(app, @CButton_1_SharpPushed, true);
            app.CButton_1_Sharp.Text = 'C#';
            app.DButton_1_Shapr = uibutton(app.UIFigure, 'push');
            app.DButton_1_Shapr.ButtonPushedFcn = createCallbackFcn(app, @DButton_1_ShaprPushed, true);
            app.DButton_1_Shapr.Text = 'D#';
            app.FButton_2_Sharp = uibutton(app.UIFigure, 'push');
            app.FButton_2_Sharp.ButtonPushedFcn = createCallbackFcn(app, @FButton_2_SharpPushed, true);
            app.FButton_2_Sharp.Text = 'F#';
            app.GButton_2_Sharp = uibutton(app.UIFigure, 'push');
            app.GButton_2_Sharp.ButtonPushedFcn = createCallbackFcn(app, @GButton_2_SharpPushed, true);
            app.GButton_2_Sharp.Text = 'G#';
            app.AButton_2_Sharp = uibutton(app.UIFigure, 'push');
            app.AButton_2_Sharp.ButtonPushedFcn = createCallbackFcn(app, @AButton_2_SharpPushed, true);
            app.AButton_2_Sharp.Text = 'A#';
            app.CButton_2 = uibutton(app.UIFigure, 'push');
            app.CButton_2.ButtonPushedFcn = createCallbackFcn(app, @CButton_2Pushed, true);
            app.CButton_2.Text = 'C';
            app.DButton_2 = uibutton(app.UIFigure, 'push');
            app.DButton_2.ButtonPushedFcn = createCallbackFcn(app, @DButton_2Pushed, true);
            app.DButton_2.Text = 'D';
            app.CButton_2_Sharp = uibutton(app.UIFigure, 'push');
            app.CButton_2_Sharp.ButtonPushedFcn = createCallbackFcn(app, @CButton_2_SharpPushed, true);
            app.CButton_2_Sharp.Text = 'C#';
            app.DButton_2_Sharp = uibutton(app.UIFigure, 'push');
            app.DButton_2_Sharp.ButtonPushedFcn = createCallbackFcn(app, @DButton_2_SharpPushed, true);
            app.DButton_2_Sharp.Text = 'D#';
            app.FButton_3 = uibutton(app.UIFigure, 'push');
            app.FButton_3.ButtonPushedFcn = createCallbackFcn(app, @FButton_3Pushed, true);
            app.FButton_3.Text = 'F';
            app.InstrumentsDropDownLabel = uilabel(app.UIFigure);
            app.InstrumentsDropDownLabel.Text = 'Instruments';
            app.InstrumentsDropDown = uidropdown(app.UIFigure);
            app.InstrumentsDropDown.Items = {'Custom', 'Electric Piano', 'Saxophone', 'Flute', 'Electric Guitar', ''};
            app.InstrumentsDropDown.ItemsData = {'0', '1', '2', '3', '4'};
            app.InstrumentsDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrumentsDropDownValueChanged, true);
            app.InstrumentsDropDown.Value = '0';
            app.HighpassSliderLabel = uilabel(app.UIFigure);
            app.HighpassSliderLabel.Text = 'High pass';
            app.HighpassSlider = uislider(app.UIFigure);
            app.HighpassSlider.ValueChangedFcn = createCallbackFcn(app, @HighpassSliderValueChanged, true);
            app.HighpassSlider.Enable = 'off';
            app.LowPassSliderLabel = uilabel(app.UIFigure);
            app.LowPassSliderLabel.Text = 'Low Pass';
            app.LowPassSlider = uislider(app.UIFigure);
            app.LowPassSlider.ValueChangedFcn = createCallbackFcn(app, @LowPassSliderValueChanged, true);
            app.LowPassSlider.Enable = 'off';
            app.OctaveKnobLabel = uilabel(app.UIFigure);
            app.OctaveKnobLabel.Text = 'Octave';
            app.OctaveKnob = uiknob(app.UIFigure, 'discrete');
            app.OctaveKnob.Items = {'-2', '-1', '0', '+1', '+2'};
            app.OctaveKnob.ValueChangedFcn = createCallbackFcn(app, @OctaveKnobValueChanged, true);
            app.OctaveKnob.Value = '0';
            app.NoteLengthLabel = uilabel(app.UIFigure);
            app.NoteLengthLabel.Text = 'Note Length';
            app.NoteLengthSlider = uislider(app.UIFigure);
            app.NoteLengthSlider.ValueChangedFcn = createCallbackFcn(app, @NoteLengthSliderValueChanged, true);
            app.NoteLengthSlider.ValueChangingFcn = createCallbackFcn(app, @NoteLengthSliderValueChanging, true);
            app.FunctionDescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.FunctionDescriptionTextAreaLabel.Text = 'Function Description';
            app.FunctionDescriptionTextArea = uitextarea(app.UIFigure);
            app.FunctionDescriptionTextArea.ValueChangedFcn = createCallbackFcn(app, @FunctionDescriptionTextAreaValueChanged, true);
            app.FunctionDescriptionTextArea.Value = {'This selection allows the user to pick a preset emulated instrument from the drop down bar. Some functions might be diabled for instruments other than "Custom."'};
            app.TremmixSliderLabel = uilabel(app.UIFigure);
            app.TremmixSliderLabel.Text = 'Tremmix';
            app.TremmixSlider = uislider(app.UIFigure);
            app.TremmixSlider.ValueChangedFcn = createCallbackFcn(app, @TremmixSliderValueChanged, true);
            app.TremmixSlider.Enable = 'off';
            app.TremtempoLabel = uilabel(app.UIFigure);
            app.TremtempoLabel.Text = 'Trem tempo';
            app.TremtempoSlider = uislider(app.UIFigure);
            app.TremtempoSlider.ValueChangedFcn = createCallbackFcn(app, @TremtempoSliderValueChanged, true);
            app.TremtempoSlider.Enable = 'off';
            app.EnableTremoloCheckBox = uicheckbox(app.UIFigure);
            app.EnableTremoloCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableTremoloCheckBoxValueChanged, true);
            app.EnableTremoloCheckBox.Text = '     Enable Tremolo';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AudioFileTextAreaLabel = uilabel(app.UIFigure);
            app.AudioFileTextAreaLabel.Text = 'Audio File: ';
            app.AudioFileTextArea = uitextarea(app.UIFigure);
            app.AudioFileTextArea.ValueChangedFcn = createCallbackFcn(app, @AudioFileTextAreaValueChanged, true);
            app.ReverseaudioCheckBox = uicheckbox(app.UIFigure);
            app.ReverseaudioCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseaudioCheckBoxValueChanged, true);
            app.ReverseaudioCheckBox.Enable = 'off';
            app.ReverseaudioCheckBox.Text = ' Reverse audio';
            app.UseFileasWaveformCheckBox = uicheckbox(app.UIFigure);
            app.UseFileasWaveformCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFileasWaveformCheckBoxValueChanged, true);
            app.UseFileasWaveformCheckBox.Enable = 'off';
            app.UseFileasWaveformCheckBox.Text = 'Use File as Waveform';
            app.UseFileasEnvelopeCheckBox = uicheckbox(app.UIFigure);
            app.UseFileasEnvelopeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFileasEnvelopeCheckBoxValueChanged, true);
            app.UseFileasEnvelopeCheckBox.Enable = 'off';
            app.UseFileasEnvelopeCheckBox.Text = 'Use File as Envelope';
            app.UseFileasTremoloCheckBox = uicheckbox(app.UIFigure);
            app.UseFileasTremoloCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFileasTremoloCheckBoxValueChanged, true);
            app.UseFileasTremoloCheckBox.Enable = 'off';
            app.UseFileasTremoloCheckBox.Text = 'Use File as Tremolo';
            app.BandRejectCheckBox = uicheckbox(app.UIFigure);
            app.BandRejectCheckBox.ValueChangedFcn = createCallbackFcn(app, @BandRejectCheckBoxValueChanged, true);
            app.BandRejectCheckBox.Enable = 'off';
            app.BandRejectCheckBox.Text = '      Band Reject';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECE45Synth

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end